classdef SWEFUN_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        CurveGenerationTab           matlab.ui.container.Tab
        MSSRButton_2                 matlab.ui.control.Button
        MSSRparametersPanel_2        matlab.ui.container.Panel
        xLabel_8                     matlab.ui.control.Label
        cut_frames                   matlab.ui.control.NumericEditField
        xLabel_7                     matlab.ui.control.Label
        cut_order                    matlab.ui.control.NumericEditField
        xLabel_6                     matlab.ui.control.Label
        cut_PSF                      matlab.ui.control.NumericEditField
        xLabel_5                     matlab.ui.control.Label
        cut_AMP                      matlab.ui.control.NumericEditField
        ProcedureTextArea            matlab.ui.control.TextArea
        Label_22                     matlab.ui.control.Label
        SigmoidCurveGenerationPanel  matlab.ui.container.Panel
        rr6                          matlab.ui.control.NumericEditField
        EditField_2Label_6           matlab.ui.control.Label
        rr5                          matlab.ui.control.NumericEditField
        EditField_2Label_5           matlab.ui.control.Label
        rr4                          matlab.ui.control.NumericEditField
        EditField_2Label_4           matlab.ui.control.Label
        rr3                          matlab.ui.control.NumericEditField
        EditField_2Label_3           matlab.ui.control.Label
        rr2                          matlab.ui.control.NumericEditField
        EditField_2Label_2           matlab.ui.control.Label
        rr1                          matlab.ui.control.NumericEditField
        ReflectivityLabel            matlab.ui.control.Label
        range500700Label             matlab.ui.control.Label
        range003008Label             matlab.ui.control.Label
        RUNButton                    matlab.ui.control.Button
        Fluor6nmEditField            matlab.ui.control.NumericEditField
        Fluor6nmEditFieldLabel       matlab.ui.control.Label
        Fluor5nmEditField            matlab.ui.control.NumericEditField
        Fluor5nmEditFieldLabel       matlab.ui.control.Label
        Fluor4nmEditField            matlab.ui.control.NumericEditField
        Fluor4nmEditFieldLabel       matlab.ui.control.Label
        Fluor3nmEditField            matlab.ui.control.NumericEditField
        Fluor3nmEditFieldLabel       matlab.ui.control.Label
        Fluor2nmEditField            matlab.ui.control.NumericEditField
        Fluor2nmEditFieldLabel       matlab.ui.control.Label
        Fluor1nmEditField            matlab.ui.control.NumericEditField
        Fluor1nmEditFieldLabel       matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aLabel                       matlab.ui.control.Label
        NEditField                   matlab.ui.control.NumericEditField
        NLabel                       matlab.ui.control.Label
        UIAxes1                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        BasicParametersTab           matlab.ui.container.Tab
        PSFadjustmentPanel           matlab.ui.container.Panel
        PSFFWHMPanel                 matlab.ui.container.Panel
        nmLabel                      matlab.ui.control.Label
        Label_28                     matlab.ui.control.Label
        FWHMEditField                matlab.ui.control.NumericEditField
        FWHMEditFieldLabel           matlab.ui.control.Label
        psfEditField                 matlab.ui.control.NumericEditField
        Knob                         matlab.ui.control.Knob
        Label_21                     matlab.ui.control.Label
        ChangePSFButton              matlab.ui.control.Button
        psfimage                     matlab.ui.control.UIAxes
        FluorescentbrightnessPanel   matlab.ui.container.Panel
        brightness6                  matlab.ui.control.NumericEditField
        Label_20                     matlab.ui.control.Label
        brightness5                  matlab.ui.control.NumericEditField
        Label_19                     matlab.ui.control.Label
        brightness4                  matlab.ui.control.NumericEditField
        Label_18                     matlab.ui.control.Label
        brightness2                  matlab.ui.control.NumericEditField
        Label_17                     matlab.ui.control.Label
        brightness3                  matlab.ui.control.NumericEditField
        Label_16                     matlab.ui.control.Label
        brightness1                  matlab.ui.control.NumericEditField
        Label_15                     matlab.ui.control.Label
        dark                         matlab.ui.control.NumericEditField
        Label_14                     matlab.ui.control.Label
        OKButton                     matlab.ui.control.Button
        OriginalimagedisplayenhancementPanel  matlab.ui.container.Panel
        strength                     matlab.ui.control.NumericEditField
        Label_13                     matlab.ui.control.Label
        OKButton_2                   matlab.ui.control.Button
        SignalSimulationTab          matlab.ui.container.Tab
        Label_29                     matlab.ui.control.Label
        RunningprogressLabel         matlab.ui.control.Label
        RUNButton_2                  matlab.ui.control.Button
        ImageDisplayPanel            matlab.ui.container.Panel
        Image_6                      matlab.ui.control.Image
        Image_5                      matlab.ui.control.Image
        Image_4                      matlab.ui.control.Image
        Image_3                      matlab.ui.control.Image
        Image_2                      matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        stack_t                      matlab.ui.control.UIAxes
        blinkingf                    matlab.ui.control.UIAxes
        originalpic                  matlab.ui.control.UIAxes
        stack_r                      matlab.ui.control.UIAxes
        SignalSeparationTab          matlab.ui.container.Tab
        RUNButton_3                  matlab.ui.control.Button
        Label_4                      matlab.ui.control.Label
        RunningprogressLabel_2       matlab.ui.control.Label
        adj6                         matlab.ui.control.UIAxes
        adj5                         matlab.ui.control.UIAxes
        adj4                         matlab.ui.control.UIAxes
        adj3                         matlab.ui.control.UIAxes
        adj2                         matlab.ui.control.UIAxes
        adj1                         matlab.ui.control.UIAxes
        solve_matrix                 matlab.ui.control.UIAxes
        CSUSRRF                      matlab.ui.control.UIAxes
    end

    properties (Access = public)
        a1 ;
        N ;
        psf;
        Fluor1;
        Fluor2;
        Fluor3;
        Fluor4;
        Fluor5;
        Fluor6;
        bright1;
        bright2;
        bright3;
        bright4;
        bright5;
        bright6;
        darkness;
        strengthen;
        mssr_amp;
        mssr_psf;
        mssr_order;
        mssr_frames;
        filename0;
        filename1;
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('functions\')
            app.Fluor1 = app.Fluor1nmEditField.Value;
            app.Fluor2 = app.Fluor2nmEditField.Value;
            app.Fluor3 = app.Fluor3nmEditField.Value;
            app.Fluor4 = app.Fluor4nmEditField.Value;
            app.Fluor5 = app.Fluor5nmEditField.Value;
            app.Fluor6 = app.Fluor6nmEditField.Value;
            app.bright1 = app.brightness1.Value;
            app.bright2 = app.brightness2.Value;
            app.bright3 = app.brightness3.Value;
            app.bright4 = app.brightness4.Value;
            app.bright5 = app.brightness5.Value;
            app.bright6 = app.brightness6.Value;
            app.darkness = app.dark.Value;
            app.psf = app.psfEditField.Value;
            app.strengthen = app.strength.Value;
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.a1 = app.aEditField.Value;
            app.N = app.NEditField.Value;
            app.Fluor1 = app.Fluor1nmEditField.Value;
            app.Fluor2 = app.Fluor2nmEditField.Value;
            app.Fluor3 = app.Fluor3nmEditField.Value;
            app.Fluor4 = app.Fluor4nmEditField.Value;
            app.Fluor5 = app.Fluor5nmEditField.Value;
            app.Fluor6 = app.Fluor6nmEditField.Value;
             assignin('base',"N",app.N);
             assignin("base","a1",app.a1);
             sigmoid_dichroic1
             plot(app.UIAxes,t,y1,'r','linewidth',0.5);
            sigmoid_dichroic_min_eig_val1
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.bright1 = app.brightness1.Value;
            app.bright2 = app.brightness2.Value;
            app.bright3 = app.brightness3.Value;
            app.bright4 = app.brightness4.Value;
            app.bright5 = app.brightness5.Value;
            app.bright6 = app.brightness6.Value;
            app.darkness = app.dark.Value;
            
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            sigmoid_dichroic1
            app.Label_29.Text = '10%';
            app.Image.Visible = 'on';
            GenerateSingleChannel_1;
            app.Label_29.Text = '20%';
            app.Image_2.Visible = "on";
            GenerateSingleChannel_2;
            app.Label_29.Text = '30%';
            app.Image_3.Visible = "on";
            GenerateSingleChannel_3;
            app.Label_29.Text = '40%';
            app.Image_4.Visible = "on";
            GenerateSingleChannel_4;
            app.Label_29.Text = '50%';
            app.Image_5.Visible = "on";
            GenerateSingleChannel_5;
            app.Label_29.Text = '60%';
            app.Image_6.Visible = "on";
            GenerateSingleChannel_6;
            app.Label_29.Text = '70%';
            CombineStack_R;
            app.Label_29.Text = '80%';
            CombineStack_T;
            app.Label_29.Text = '100%';
        end

        % Button pushed function: RUNButton_3
        function RUNButton_3Pushed(app, event)
             msgbox('Separation in progress','Running','help'); 
             sigmoid_dichroic1
             app.filename0=strcat(cd,'\RawData\stack\stack_R.tif');
             app.filename1=strcat(cd,'\RawData\stack\stack_T.tif');
             app.Label_4.Text = '0%';
             CSU_SRRF_3_0;
             app.Label_4.Text = '15%';
             CSU_SRRF_3_1;
             app.Label_4.Text = '30%';
             CSU_SRRF_3_2;
             app.Label_4.Text = '45%';
             CSU_SRRF_3_3;
             app.Label_4.Text = '60%';
             CSU_SRRF_3_4;
             app.Label_4.Text = '80%';
             CSU_SRRF_3_5;
             solve_matrix_32bit_simulation_4_balance;
             app.Label_4.Text = '100%';
             msgbox('Separation ended','Finish','help');
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)
            app.strengthen = app.strength.Value;
        end

        % Value changed function: Knob
        function KnobValueChanged2(app, event)
            value = app.Knob.Value;
            xx=-0.5:0.02:0.5; %pixel size 20nm
            app.psfEditField.Value  = app.Knob.Value;
            app.FWHMEditField.Value = app.psfEditField.Value*2*sqrt(2*log(2))*1000;
            app.psf = app.psfEditField.Value;
            PSFX=gaussmf(xx,[app.psf 0]);   %fwhm=223.8nm for 0.095
            PSFXY=PSFX'*PSFX; 
            imshow(PSFXY,'parent',app.psfimage);
        end

        % Button pushed function: ChangePSFButton
        function ChangePSFButtonPushed2(app, event)
            xx=-0.5:0.02:0.5; %pixel size 20nm
            app.psf = app.psfEditField.Value;
            app.FWHMEditField.Value = app.psfEditField.Value*2*sqrt(2*log(2))*1000;
            PSFX=gaussmf(xx,[app.psf 0]);   %fwhm=223.8nm for 0.095
            PSFXY=PSFX'*PSFX;
           imshow(PSFXY,'parent',app.psfimage);


        end

        % Callback function
        function Button_6Pushed(app, event)
            run colormain.mlapp;
            delete (app.UIFigure);
        end

        % Value changed function: FWHMEditField
        function FWHMEditFieldValueChanged(app, event)
            value = app.FWHMEditField.Value;
            app.psfEditField.Value = app.FWHMEditField.Value/(2*sqrt(2*log(2))*1000);
        end

        % Callback function
        function MSSRButtonPushed(app, event)
            app.mssr_amp= app.cut_AMP1.Value;
            app.mssr_psf= app.cut_PSF1.Value;
            app.mssr_order= app.cut_order1.Value;
            app.mssr_frames= app.cut_frames1.Value;
            mssr_re;
        end

        % Button pushed function: MSSRButton_2
        function MSSRButton_2Pushed(app, event)

            app.mssr_amp= app.cut_AMP.Value;
            app.mssr_psf= app.cut_PSF.Value;
            app.mssr_order= app.cut_order.Value;
            app.mssr_frames= app.cut_frames.Value;
            mssr_re;
            msgbox('MSSR finish','Finish','help');
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Synchronous Multicolor Super-resolution Microscopic Imaging Platform (Beyond the Diffraction Limit)';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'picture', 'th.jpg');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CurveGenerationTab = uitab(app.TabGroup);
            app.CurveGenerationTab.Tooltip = {''};
            app.CurveGenerationTab.Title = 'Curve Generation';
            app.UIAxes = uiaxes(app.CurveGenerationTab);
            title(app.UIAxes, 'Dichroics with Sigmoid Transmitivity ')
            xlabel(app.UIAxes, 'Wavelength')
            ylabel(app.UIAxes, 'Transmitivity')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [450 500 550 600 650 700 750];
            app.UIAxes.XTickLabel = {'450nm'; '500nm'; '550nm'; '600nm'; '650nm'; '700nm'; '750nm'};
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2 = uiaxes(app.CurveGenerationTab);
            title(app.UIAxes2, 'Eigenvalue')
            xlabel(app.UIAxes2, 'Wavelength')
            ylabel(app.UIAxes2, 'a')
            app.UIAxes2.XTick = [];
            app.UIAxes1 = uiaxes(app.CurveGenerationTab);
            title(app.UIAxes1, 'Condition number')
            xlabel(app.UIAxes1, 'Wavelength')
            ylabel(app.UIAxes1, 'a')
            app.UIAxes1.XTick = [];
            app.SigmoidCurveGenerationPanel = uipanel(app.CurveGenerationTab);
            app.SigmoidCurveGenerationPanel.Title = 'Sigmoid Curve Generation ';
            app.NLabel = uilabel(app.SigmoidCurveGenerationPanel);
            app.NLabel.Text = 'N ';
            app.NEditField = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.NEditField.Value = 580;
            app.aLabel = uilabel(app.SigmoidCurveGenerationPanel);
            app.aLabel.Text = 'a ';
            app.aEditField = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.aEditField.Value = 0.05;
            app.Fluor1nmEditFieldLabel = uilabel(app.SigmoidCurveGenerationPanel);
            app.Fluor1nmEditFieldLabel.Text = 'Fluor1(nm)';
            app.Fluor1nmEditField = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.Fluor1nmEditField.Tooltip = {'一号标记荧光中心波长'};
            app.Fluor1nmEditField.Value = 525;
            app.Fluor2nmEditFieldLabel = uilabel(app.SigmoidCurveGenerationPanel);
            app.Fluor2nmEditFieldLabel.Text = 'Fluor2(nm)';
            app.Fluor2nmEditField = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.Fluor2nmEditField.Tooltip = {'二号标记荧光中心波长'};
            app.Fluor2nmEditField.Value = 545;
            app.Fluor3nmEditFieldLabel = uilabel(app.SigmoidCurveGenerationPanel);
            app.Fluor3nmEditFieldLabel.Text = 'Fluor3(nm)';
            app.Fluor3nmEditField = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.Fluor3nmEditField.Tooltip = {'三号标记荧光中心波长'};
            app.Fluor3nmEditField.Value = 565;
            app.Fluor4nmEditFieldLabel = uilabel(app.SigmoidCurveGenerationPanel);
            app.Fluor4nmEditFieldLabel.Text = 'Fluor4(nm)';
            app.Fluor4nmEditField = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.Fluor4nmEditField.Tooltip = {'四号标记荧光中心波长'};
            app.Fluor4nmEditField.Value = 585;
            app.Fluor5nmEditFieldLabel = uilabel(app.SigmoidCurveGenerationPanel);
            app.Fluor5nmEditFieldLabel.Text = 'Fluor5(nm)';
            app.Fluor5nmEditField = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.Fluor5nmEditField.Tooltip = {'五号标记荧光中心波长'};
            app.Fluor5nmEditField.Value = 625;
            app.Fluor6nmEditFieldLabel = uilabel(app.SigmoidCurveGenerationPanel);
            app.Fluor6nmEditFieldLabel.Text = 'Fluor6(nm)';
            app.Fluor6nmEditField = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.Fluor6nmEditField.Tooltip = {'六号标记荧光中心波长'};
            app.Fluor6nmEditField.Value = 655;
            app.RUNButton = uibutton(app.SigmoidCurveGenerationPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Icon = fullfile(pathToMLAPP, 'picture', '1486395886-checkmark_80611.png');
            app.RUNButton.Tooltip = {'Generate different curves by changing the values of a and N. And set different center wavelengths to obtain the corresponding reflectivity and transmittance of the curve.'};
            app.RUNButton.Text = 'RUN';
            app.range003008Label = uilabel(app.SigmoidCurveGenerationPanel);
            app.range003008Label.Text = 'range(0.03~0.08）';
            app.range500700Label = uilabel(app.SigmoidCurveGenerationPanel);
            app.range500700Label.Text = 'range(500~700）';
            app.ReflectivityLabel = uilabel(app.SigmoidCurveGenerationPanel);
            app.ReflectivityLabel.Text = 'Reflectivity';
            app.rr1 = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.EditField_2Label_2 = uilabel(app.SigmoidCurveGenerationPanel);
            app.EditField_2Label_2.Text = 'Reflectivity';
            app.rr2 = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.EditField_2Label_3 = uilabel(app.SigmoidCurveGenerationPanel);
            app.EditField_2Label_3.Text = 'Reflectivity';
            app.rr3 = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.EditField_2Label_4 = uilabel(app.SigmoidCurveGenerationPanel);
            app.EditField_2Label_4.Text = 'Reflectivity';
            app.rr4 = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.EditField_2Label_5 = uilabel(app.SigmoidCurveGenerationPanel);
            app.EditField_2Label_5.Text = 'Reflectivity';
            app.rr5 = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.EditField_2Label_6 = uilabel(app.SigmoidCurveGenerationPanel);
            app.EditField_2Label_6.Text = 'Reflectivity';
            app.rr6 = uieditfield(app.SigmoidCurveGenerationPanel, 'numeric');
            app.Label_22 = uilabel(app.CurveGenerationTab);
            app.Label_22.Text = 'Procedure：';
            app.ProcedureTextArea = uitextarea(app.CurveGenerationTab);
            app.ProcedureTextArea.Editable = 'off';
            app.ProcedureTextArea.Value = {'1、Enter N, a, and center wavelength to generate a curve.'; '2、Adjust simulation parameters.'; '3、Generate six channel signals and synthesize reflection and transmission channel signals.'; '4、Separate reflection and transmission channel signals'};
            app.MSSRparametersPanel_2 = uipanel(app.CurveGenerationTab);
            app.MSSRparametersPanel_2.Title = 'MSSR parameters';
            app.cut_AMP = uieditfield(app.MSSRparametersPanel_2, 'numeric');
            app.cut_AMP.Value = 2;
            app.xLabel_5 = uilabel(app.MSSRparametersPanel_2);
            app.xLabel_5.Text = 'AMP：';
            app.cut_PSF = uieditfield(app.MSSRparametersPanel_2, 'numeric');
            app.cut_PSF.Value = 20;
            app.xLabel_6 = uilabel(app.MSSRparametersPanel_2);
            app.xLabel_6.Text = 'PSF：';
            app.cut_order = uieditfield(app.MSSRparametersPanel_2, 'numeric');
            app.cut_order.Value = 1;
            app.xLabel_7 = uilabel(app.MSSRparametersPanel_2);
            app.xLabel_7.Text = 'Order：';
            app.cut_frames = uieditfield(app.MSSRparametersPanel_2, 'numeric');
            app.cut_frames.Value = 100;
            app.xLabel_8 = uilabel(app.MSSRparametersPanel_2);
            app.xLabel_8.Text = 'frames：';
            app.MSSRButton_2 = uibutton(app.CurveGenerationTab, 'push');
            app.MSSRButton_2.ButtonPushedFcn = createCallbackFcn(app, @MSSRButton_2Pushed, true);
            app.MSSRButton_2.Tooltip = {'Run MSSR algorithm'};
            app.MSSRButton_2.Text = 'MSSR';
            app.BasicParametersTab = uitab(app.TabGroup);
            app.BasicParametersTab.Title = 'Basic Parameters';
            app.OriginalimagedisplayenhancementPanel = uipanel(app.BasicParametersTab);
            app.OriginalimagedisplayenhancementPanel.Title = 'Original image display enhancement';
            app.OKButton_2 = uibutton(app.OriginalimagedisplayenhancementPanel, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Icon = fullfile(pathToMLAPP, 'picture', '1486395886-checkmark_80611.png');
            app.OKButton_2.Tooltip = {'Due to the weak intensity of the original image, it cannot be clearly displayed in MATLAB, and it is necessary to increase the display intensity.'};
            app.OKButton_2.Text = 'OK';
            app.Label_13 = uilabel(app.OriginalimagedisplayenhancementPanel);
            app.Label_13.Text = 'Enhancement factor';
            app.strength = uieditfield(app.OriginalimagedisplayenhancementPanel, 'numeric');
            app.strength.Value = 500;
            app.FluorescentbrightnessPanel = uipanel(app.BasicParametersTab);
            app.FluorescentbrightnessPanel.Title = 'Fluorescent brightness';
            app.OKButton = uibutton(app.FluorescentbrightnessPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Icon = fullfile(pathToMLAPP, 'picture', '1486395886-checkmark_80611.png');
            app.OKButton.Tooltip = {'Adjusting the brightness and dark intensity of fluorescence'};
            app.OKButton.Text = 'OK';
            app.Label_14 = uilabel(app.FluorescentbrightnessPanel);
            app.Label_14.Text = 'Dark brightness';
            app.dark = uieditfield(app.FluorescentbrightnessPanel, 'numeric');
            app.Label_15 = uilabel(app.FluorescentbrightnessPanel);
            app.Label_15.Text = 'Channel 1';
            app.brightness1 = uieditfield(app.FluorescentbrightnessPanel, 'numeric');
            app.brightness1.Value = 200;
            app.Label_16 = uilabel(app.FluorescentbrightnessPanel);
            app.Label_16.Text = 'Channel 3';
            app.brightness3 = uieditfield(app.FluorescentbrightnessPanel, 'numeric');
            app.brightness3.Value = 200;
            app.Label_17 = uilabel(app.FluorescentbrightnessPanel);
            app.Label_17.Text = 'Channel 2';
            app.brightness2 = uieditfield(app.FluorescentbrightnessPanel, 'numeric');
            app.brightness2.Value = 200;
            app.Label_18 = uilabel(app.FluorescentbrightnessPanel);
            app.Label_18.Text = 'Channel 4';
            app.brightness4 = uieditfield(app.FluorescentbrightnessPanel, 'numeric');
            app.brightness4.Value = 80;
            app.Label_19 = uilabel(app.FluorescentbrightnessPanel);
            app.Label_19.Text = 'Channel 5';
            app.brightness5 = uieditfield(app.FluorescentbrightnessPanel, 'numeric');
            app.brightness5.Value = 80;
            app.Label_20 = uilabel(app.FluorescentbrightnessPanel);
            app.Label_20.Text = 'Channel 6';
            app.brightness6 = uieditfield(app.FluorescentbrightnessPanel, 'numeric');
            app.brightness6.Value = 80;
            app.PSFadjustmentPanel = uipanel(app.BasicParametersTab);
            app.PSFadjustmentPanel.Title = 'PSF adjustment';
            app.psfimage = uiaxes(app.PSFadjustmentPanel);
            title(app.psfimage, 'Point Spread Function (PSF)      ')
            zlabel(app.psfimage, 'Z')
            app.psfimage.XTick = [];
            app.psfimage.YTick = [];
            app.psfimage.Visible = 'off';
            app.ChangePSFButton = uibutton(app.PSFadjustmentPanel, 'push');
            app.ChangePSFButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePSFButtonPushed2, true);
            app.ChangePSFButton.Icon = fullfile(pathToMLAPP, 'picture', '1486395886-checkmark_80611.png');
            app.ChangePSFButton.Tooltip = {'Determine the PSF size for subsequent simulations based on the data in the numerical box.'};
            app.ChangePSFButton.Text = 'Change PSF';
            app.PSFFWHMPanel = uipanel(app.PSFadjustmentPanel);
            app.PSFFWHMPanel.Title = 'PSF（FWHM）';
            app.Label_21 = uilabel(app.PSFFWHMPanel);
            app.Label_21.Text = '';
            app.Knob = uiknob(app.PSFFWHMPanel, 'continuous');
            app.Knob.Limits = [0.04 0.14];
            app.Knob.MajorTicks = [0.04 0.06 0.08 0.1 0.12 0.14];
            app.Knob.MajorTickLabels = {'94.2nm', '141.3nm', '188.4nm', '235.5nm', '282.6nm', '329.7nm'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged2, true);
            app.Knob.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29];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
            app.Knob.Tooltip = {'Turning the knob can display PSFs of different sizes.'};
            app.Knob.Value = 0.0849321800288019;
            app.psfEditField = uieditfield(app.PSFFWHMPanel, 'numeric');
            app.psfEditField.Value = 0.0849321800288019;
            app.FWHMEditFieldLabel = uilabel(app.PSFFWHMPanel);
            app.FWHMEditFieldLabel.Text = 'FWHM';
            app.FWHMEditField = uieditfield(app.PSFFWHMPanel, 'numeric');
            app.FWHMEditField.ValueChangedFcn = createCallbackFcn(app, @FWHMEditFieldValueChanged, true);
            app.FWHMEditField.Value = 200;
            app.Label_28 = uilabel(app.PSFFWHMPanel);
            app.Label_28.Text = 'σ';
            app.nmLabel = uilabel(app.PSFFWHMPanel);
            app.nmLabel.Text = '(nm)';
            app.SignalSimulationTab = uitab(app.TabGroup);
            app.SignalSimulationTab.Title = 'Signal Simulation';
            app.ImageDisplayPanel = uipanel(app.SignalSimulationTab);
            app.ImageDisplayPanel.Title = 'Image Display';
            app.stack_r = uiaxes(app.ImageDisplayPanel);
            title(app.stack_r, 'Reflection Channel                 ')
            zlabel(app.stack_r, 'Z')
            app.stack_r.XTick = [];
            app.stack_r.YTick = [];
            app.stack_r.Visible = 'off';
            app.originalpic = uiaxes(app.ImageDisplayPanel);
            title(app.originalpic, 'Original Image         ')
            zlabel(app.originalpic, 'Z')
            app.originalpic.XTick = [];
            app.originalpic.YTick = [];
            app.originalpic.Visible = 'off';
            app.blinkingf = uiaxes(app.ImageDisplayPanel);
            title(app.blinkingf, 'Fluctuation Image')
            zlabel(app.blinkingf, 'Z')
            app.blinkingf.XTick = [];
            app.blinkingf.YTick = [];
            app.blinkingf.Visible = 'off';
            app.stack_t = uiaxes(app.ImageDisplayPanel);
            title(app.stack_t, 'Transmission Channel        ')
            zlabel(app.stack_t, 'Z')
            app.stack_t.XTick = [];
            app.stack_t.YTick = [];
            app.stack_t.Visible = 'off';
            app.Image = uiimage(app.ImageDisplayPanel);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'picture', 'picture2', 'target-1-adjust-2.png');
            app.Image_2 = uiimage(app.ImageDisplayPanel);
            app.Image_2.Visible = 'off';
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'picture', 'picture2', 'target-2-adjust-2.png');
            app.Image_3 = uiimage(app.ImageDisplayPanel);
            app.Image_3.Visible = 'off';
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'picture', 'picture2', 'target-3-adjust-2.png');
            app.Image_4 = uiimage(app.ImageDisplayPanel);
            app.Image_4.Visible = 'off';
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'picture', 'picture2', 'target-4-adjust-2.png');
            app.Image_5 = uiimage(app.ImageDisplayPanel);
            app.Image_5.Visible = 'off';
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'picture', 'picture2', 'target-5-adjust-2.png');
            app.Image_6 = uiimage(app.ImageDisplayPanel);
            app.Image_6.Visible = 'off';
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'picture', 'picture2', 'target-6-adjust-2.png');
            app.RUNButton_2 = uibutton(app.SignalSimulationTab, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Tooltip = {'Generate reflection channel and transmission channel signals'};
            app.RUNButton_2.Text = 'RUN';
            app.RunningprogressLabel = uilabel(app.SignalSimulationTab);
            app.RunningprogressLabel.Text = 'Running progress：';
            app.Label_29 = uilabel(app.SignalSimulationTab);
            app.Label_29.Text = '0';
            app.SignalSeparationTab = uitab(app.TabGroup);
            app.SignalSeparationTab.Title = 'Signal Separation';
            app.CSUSRRF = uiaxes(app.SignalSeparationTab);
            title(app.CSUSRRF, 'Transmission Channel')
            zlabel(app.CSUSRRF, 'Z')
            app.CSUSRRF.XTick = [];
            app.CSUSRRF.YTick = [];
            app.CSUSRRF.Visible = 'off';
            app.solve_matrix = uiaxes(app.SignalSeparationTab);
            title(app.solve_matrix, 'Reflection Channel  ')
            zlabel(app.solve_matrix, 'Z')
            app.solve_matrix.XTick = [];
            app.solve_matrix.YTick = [];
            app.solve_matrix.Visible = 'off';
            app.adj1 = uiaxes(app.SignalSeparationTab);
            title(app.adj1, '    ')
            zlabel(app.adj1, 'Z')
            app.adj1.XTick = [];
            app.adj1.YTick = [];
            app.adj1.Visible = 'off';
            app.adj2 = uiaxes(app.SignalSeparationTab);
            title(app.adj2, '    ')
            zlabel(app.adj2, 'Z')
            app.adj2.XTick = [];
            app.adj2.YTick = [];
            app.adj2.Visible = 'off';
            app.adj3 = uiaxes(app.SignalSeparationTab);
            title(app.adj3, '   ')
            zlabel(app.adj3, 'Z')
            app.adj3.XTick = [];
            app.adj3.YTick = [];
            app.adj3.Visible = 'off';
            app.adj4 = uiaxes(app.SignalSeparationTab);
            title(app.adj4, '   ')
            zlabel(app.adj4, 'Z')
            app.adj4.XTick = [];
            app.adj4.YTick = [];
            app.adj4.Visible = 'off';
            app.adj5 = uiaxes(app.SignalSeparationTab);
            title(app.adj5, '    ')
            zlabel(app.adj5, 'Z')
            app.adj5.XTick = [];
            app.adj5.YTick = [];
            app.adj5.Visible = 'off';
            app.adj6 = uiaxes(app.SignalSeparationTab);
            title(app.adj6, '    ')
            zlabel(app.adj6, 'Z')
            app.adj6.XTick = [];
            app.adj6.YTick = [];
            app.adj6.Visible = 'off';
            app.RunningprogressLabel_2 = uilabel(app.SignalSeparationTab);
            app.RunningprogressLabel_2.Text = 'Running progress：';
            app.Label_4 = uilabel(app.SignalSeparationTab);
            app.Label_4.Text = '0';
            app.RUNButton_3 = uibutton(app.SignalSeparationTab, 'push');
            app.RUNButton_3.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_3Pushed, true);
            app.RUNButton_3.Tooltip = {'Calculate correlation function and separate signals'};
            app.RUNButton_3.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SWEFUN_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end